classdef BasicApp20180428 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        UIAxes                  matlab.ui.control.UIAxes
        SweepcontrolPanel       matlab.ui.container.Panel
        ContinueousButton       matlab.ui.control.StateButton
        SingleSweepButton       matlab.ui.control.Button
        LoopLabel               matlab.ui.control.Label
        timesLabel              matlab.ui.control.Label
        LogName                 matlab.ui.control.Label
        IntervalTime            matlab.ui.control.Label
        LoggingPanel            matlab.ui.container.Panel
        BrowseButton            matlab.ui.control.Button
        LocationEditFieldLabel  matlab.ui.control.Label
        LocationEditField       matlab.ui.control.EditField
        LogButton               matlab.ui.control.StateButton
        TagNoteEditFieldLabel   matlab.ui.control.Label
        TagNoteEditField        matlab.ui.control.EditField
        TagAtLabel              matlab.ui.control.Label
        TagButton               matlab.ui.control.Button
        DisplayButtonGroup      matlab.ui.container.ButtonGroup
        ResistanceButton        matlab.ui.control.ToggleButton
        ResonanceFreqButton     matlab.ui.control.ToggleButton
        DeltaF                  matlab.ui.control.ToggleButton
        SetInitialFreq          matlab.ui.control.Button
        InitialFreq             matlab.ui.control.Label
        InitialIndex            matlab.ui.control.Label
    end

    
    properties (Access = private)
        N % Loop Number
        CurrentLogLocation 
        StartTime % Description
        Tag % Description
        DataArray % Time R ResFreq SmoothFreq 
        ArrayResonanceFreq = []; % Description
        InitResonanceF %For delta F
        InitIndex %for show delta 
        
        framelen % Sgolay window
        order % Sgolay order
    end
    
    methods (Access = private)
        
        function results = RunContinueButton(app)
            tic;
            last_loop_time =0;
            while 1
                if app.ContinueousButton.Value == 1
          
                    
                    dir_content = dir('log'); % list the file from log folder
                    
                    filenames = {dir_content.name}; % get file name from struct
                    current_files = filenames; % current list of file name
                    
                    pause(0.1);
                    dir_content = dir('log');
                    filenames = {dir_content.name};
                    new_files = setdiff(filenames,current_files);
                    if ~isempty(new_files)
                        % deal with the new files
                        
                        start_time =toc;
                        
                        %disp(new_files)
                        
                        fullfilenames = fullfile('log',new_files);
                        delimiterIn = ' ';
                        headerlinesIn = 2;
                        A = importdata(fullfilenames{1},delimiterIn,headerlinesIn);
                        
                        z0 = 50;
                        % define variable
                        freq_Array =[];
                        s11_Array = [];
                        y_Array = [];
                        
                        for n = 1:length(A.data)
                            %extract frequency from dataset
                            freq = A.data(n,1)*1E6; %convert freq data to MHz
                            freq_Array = [freq_Array; freq];
                            
                            %extract complex s parameter from dataset
                            s11 =complex(A.data(n,2),A.data(n,3));
                            s11_Array = [s11_Array; s11];
                            
                            %Directly extract to Admittance
                            y_param = s2y(s11,z0);
                            y_Array = [y_Array; y_param];
                            
                        end
                        [M,I] = max(real(y_Array));
                        
                        a = [400 11.400000000000 27.52015748915769 0]; %Initial condition for curve fit
                        % a = [0 0 0 0];
                        % R = 400 ohm, L= 11.4 mH, C = 27.52 fF, conductance diff = 0}
                        
                        % Curve fitting and fine minimum point
                        options = optimset('MaxFunEvals',100000,'MaxIter',10000,'TolX',1e-10,'TolFun',1e-10,'Display','off');
                        % MaxFunEvals = Maximum number of function evaluations
                        % MaxIter = Maximum number of iterations allowed
                        % TolX = Termination tolerance on x, a positive scalar. The default value is 1e-4
                        % TolFun = Termination tolerance on the function value, a positive scalar. The default is 1e-4
                        % Display = 'final' displays just the final output
                        
                        
                        [x0,fval,exitflag]=fminsearch(@(v) fitcon2(app,v,freq_Array,real(y_Array)),a,options);
                        %disp(x0)
                        
                        %Plot real and imaginary Z on same axis
                        
                        str = sprintf('R1 = %f ohm, L1 = %f mH, C1 = %f fF',x0(1),x0(2),x0(3));
                        dim = [0.2 0.5 0.3 0.3];


                        %title ('Conductance plot')
                        %legend('measured G','Calculated G')
                        
                        %xlabel('frequency(Hz)')
                        %ylabel('Conductance(siemens)')
                        
                        % annotation(app.UIAxes,'textbox',dim,'String',str,'FitBoxToText','on');


                        time_total = toc;
                        app.N = app.N+1;
                        
                        
                        %Tag time after end all calculation
                        TaggingTime = floor(etime(clock,app.StartTime));
                        
                        app.LoopLabel.Text = strcat('Current Loop num: ',int2str(app.N));
                        app.timesLabel.Text = sprintf('Running time: %s s', int2str(TaggingTime));
                        app.IntervalTime.Text =  sprintf('Sampling time: %s s',num2str(time_total-last_loop_time));


                        %plot(app.UIAxes,freq_Array,real(y_Array),freq_Array,concal2(app,x0,freq_Array))
                        ResonanceFreq = (1/(2*pi*sqrt(x0(2)*x0(3))))*1e9;
                        app.InitResonanceF = ResonanceFreq;
                        
                        % Dataarray = [Time R ResonanceFreq SmoothFreq SmoothR MaxF] = 6 col
                        app.DataArray = [app.DataArray;[TaggingTime x0(1) ResonanceFreq 0 0 freq_Array(I)]];
                        [m,n] = size(app.DataArray);
                        app.InitIndex = m;
                        
                        Initindex = str2num(app.InitialIndex.Text);
      %Plot-------------------------------- Managing
                       
                        if size(app.DataArray,1) > app.framelen
                            SgolayResonanceFreq = sgolayfilt(app.DataArray(:,3),app.order,app.framelen); %Use data from col 3 which is Frequency 
                            app.DataArray(:,4) = SgolayResonanceFreq; %place smooth freq data to col 4
                            
                            SgolayResonanceRes =  sgolayfilt(app.DataArray(:,2),app.order,app.framelen); %Use data from col 2 which is Resistance
                            app.DataArray(:,5) = SgolayResonanceRes; %place smooth freq data to col 5
                            
                            if app.ResistanceButton.Value == 1
                                %Plot Resistance
                                plot(app.UIAxes,app.DataArray(:,1),app.DataArray(:,2),app.DataArray(:,1),app.DataArray(:,5));
                                legend(app.UIAxes,'Curved Fitted R','Smoothed Curved Fitted R');
                                legend(app.UIAxes,'Location','northwest');
                            elseif app.ResonanceFreqButton.Value == 1
                                %Plot Freq
                                plot(app.UIAxes,app.DataArray(:,1),app.DataArray(:,3),app.DataArray(:,1),app.DataArray(:,4),app.DataArray(:,1),app.DataArray(:,6),'bo');
                                legend(app.UIAxes,'Curved Fitted F','Smoothed Curved Fitted F','Peak F');
                                legend(app.UIAxes,'Location','northwest');
                            elseif app.DeltaF.Value == 1
                                    %Plot Delta Freq
                                plot(app.UIAxes,app.DataArray(Initindex:end,1),app.DataArray(Initindex:end,3)-str2double(app.InitialFreq.Text),app.DataArray(Initindex:end,1),app.DataArray(Initindex:end,4)-str2double(app.InitialFreq.Text));
                                legend(app.UIAxes,'Curved Fitted F','Smoothed Curved Fitted F');
                                legend(app.UIAxes,'Location','northwest');
                            end
                        else
                            if app.ResistanceButton.Value == 1
                                plot(app.UIAxes,app.DataArray(:,1),app.DataArray(:,2));
                                legend(app.UIAxes,'Curved Fitted measured R');
                                legend(app.UIAxes,'Location','northwest');
                            elseif app.ResonanceFreqButton.Value == 1
                                plot(app.UIAxes,app.DataArray(:,1),app.DataArray(:,3),app.DataArray(:,1),app.DataArray(:,6),'bo');
                                legend(app.UIAxes,'Curved Fitted F','Peak F');
                                legend(app.UIAxes,'Location','northwest');
                            end
                        end


                        %SgolayResonanceFreqNow = app.DataArray(end,4);
                        %update last loop time ticker
                        last_loop_time = time_total;
                        app.LogName.Text = new_files{1};
                        
                        %pause(0.1); %add delay for some effect????
                        current_files = filenames;
                        
                        class(app.DataArray)
                        
                        
                        % Write log file format
                        if app.LogButton.Value == 1
                            FileName = app.LocationEditField.Value; 
                            %Data = [TaggingTime, x0(1:end-1)];
                            %dlmwrite(FileName, Data, '-append');
                            fid = fopen(FileName, 'a') ;
                            %[Time R L C ResonanceFreq SmoothResonanceFreq SmoothRes MaxF Tag] total 9 col 
                            fprintf(fid, '%d,%6.3f,%6.5f,%6.5f,%10.2f,%10.2f,%6.5f,%10.2f,%s\n', TaggingTime,x0(1:end-1),ResonanceFreq,app.DataArray(end,4),app.DataArray(end,5),freq_Array(I),app.Tag) ;
                            fclose(fid) ;
                            if app.Tag ~= ""
                                app.TagAtLabel.Text = sprintf('Tagging %s at : %d s',app.Tag, TaggingTime);
                            end
                            app.Tag = "";
                            
                            %Write savitzky smoothing result on 'F' cell column
                        %This command takes a lot of time need to fix
                        
                            %xlswrite(FileName, app.DataArray(:,4), 1, 'F2');
                            %xlswrite(FileName, app.DataArray(:,5), 1, 'G2'); 
                            
                        end


                    else
                        %fprintf('no new files\n')
                    end
                else
                    %Kill VNWA process
                    dos('taskkill /F /IM VNWA.exe');
                    
                    %Break app if value change
                    break
                end
            end
        end


    end
    
    methods (Access = public)
        
        function results = concal2(app,v,fre)
            x = v(1);
            y = v(2)/1000;
            z = v(3)*1e-15;
            results = x./(x.^2+(2*pi*fre*y-1./2/pi./fre/z).^2);
        end
        
        function results = fitcon2(app,v,Fre,Conductance)
            x = v(1);%ohm
            y = v(2)/1000;%mH
            z = v(3)*1e-15;%fF
            
            f = x./(x.^2+(2*pi*Fre*y-1./2/pi./Fre/z).^2)-Conductance;
            results=f'*f;
            
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.N = 0;
            app.CurrentLogLocation = '';
            app.StartTime = clock;
            app.Tag = ""
            app.DataArray = [];
            app.framelen = 33;
            app.order = 3;
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [filename, pathname] = uiputfile('*.csv', 'Save a .csv log file');
            app.LocationEditField.Value  = fullfile(pathname,filename);
            
            %disp(app.CurrentLogLocation);
            %disp(app.LocationEditField.Value);
            
        end

        % Value changed function: ContinueousButton
        function ContinueousButtonValueChanged(app, event)
            if app.ContinueousButton.Value == 1
                app.SingleSweepButton.Enable = 'off'
                %Start sweep
                system('C:\VNWA\VNWA.exe -silent -csweep&')
                %Call loop function
                RunContinueButton(app);
            else
                app.SingleSweepButton.Enable = 'on'
            end
        end

        % Selection changed function: DisplayButtonGroup
        function DisplayButtonGroupSelectionChanged(app, event)
            selectedButton = app.DisplayButtonGroup.SelectedObject;
            if app.ResistanceButton.Value == 1
                app.UIAxes.YLabel.String = 'resistance (Ohm)';
                app.UIAxes.Title.String = 'Series Resistance';
                app.SetInitialFreq.Visible = 'off'
                app.SetInitialFreq.Enable = 'off'
            elseif   app.ResonanceFreqButton.Value == 1
                app.UIAxes.YLabel.String = 'frequency (Hz)';
                app.UIAxes.Title.String = 'Resonance frequency';
                app.SetInitialFreq.Visible = 'off'
                app.SetInitialFreq.Enable = 'off'
            elseif app.DeltaF.Value == 1
                app.UIAxes.YLabel.String = 'frequency (Hz)';
                app.UIAxes.Title.String = 'Delta Resonance frequency';
                app.SetInitialFreq.Visible = 'on'
                app.SetInitialFreq.Enable = 'on'
            end
        end

        % Value changed function: LogButton
        function LogButtonValueChanged(app, event)
            value = app.LogButton.Value;
            if value == 1
                app.StartTime = clock;
                app.DataArray =[];
                app.LogButton.Text = 'Logging...'; 
                %FileName = ['QCM',datestr(now, 'yyyymmdd_HHMMSS'),'.csv'];
                
                %if file location is change write header
                if  ~strcmp(app.CurrentLogLocation,app.LocationEditField.Value)
                    FileLocation = app.LocationEditField.Value;
                    fid = fopen(FileLocation, 'w') ;
                    fprintf(fid, '%s\n', 'time,R1,L1,C1,ResFreq,SmoothFreq,SmoothR,PeakFreq,Note') ;
                    fclose(fid) ;
                    app.CurrentLogLocation = app.LocationEditField.Value;
                    %dlmwrite(FileName, x0(1:end-1), '-append') ;
                end
            else
                app.LogButton.Text = 'Not Log'; 
            end
        end

        % Button pushed function: SingleSweepButton
        function SingleSweepButtonPushed(app, event)
            app.ContinueousButton.Enable = 'off';
            system('C:\VNWA\VNWA.exe C:\VNWA\test.scr -debug');
            
            delimiterIn = ' ';
            headerlinesIn = 2;
            A = importdata('s11.csv',delimiterIn,headerlinesIn);
            % initialization
            z0 = 50;
            % define variable
            freq_Array =[];
            s11_Array = [];
            y_Array = [];
            
            for n = 1:length(A.data)
                %extract frequency from dataset
                freq = A.data(n,1)*1E6; %convert freq data to MHz
                freq_Array = [freq_Array; freq];
                
                %extract complex s parameter frm dataset
                s11 =complex(A.data(n,2),A.data(n,3));
                s11_Array = [s11_Array; s11];
                
                %
                y_param = s2y(s11,z0);
                y_Array = [y_Array; y_param];
                
            end


            a = [400 11.400000000000 27.52015748915769 0]; %Initial condition for curve fit
            % a = [0 0 0 0];
            % R = 400 ohm, L= 11.4 mH, C = 27.52 fF, conductance diff = 0}
            
            % Curve fitting and fine minimum point
            options = optimset('MaxFunEvals',100000,'MaxIter',10000,'TolX',1e-10,'TolFun',1e-10,'Display','iter');
            % MaxFunEvals = Maximum number of function evaluations
            % MaxIter = Maximum number of iterations allowed
            % TolX = Termination tolerance on x, a positive scalar. The default value is 1e-4
            % TolFun = Termination tolerance on the function value, a positive scalar. The default is 1e-4
            % Display = 'final' displays just the final output
            
            
            [x0,fval,exitflag]=fminsearch(@(v) fitcon2(v,freq_Array,real(y_Array)),a,options);
            disp(x0)
            
            freqResonance = (1/(2*pi*sqrt(x0(2)*x0(3))))*1e9;
     
            TaggingTime = floor(etime(clock,app.StartTime));
            
            plot(app.UIAxes,freq_Array,real(y_Array),freq_Array,concal2(app,x0,freq_Array))
            %plot(app.UIAxes,TaggingTime,freqResonance);
            %drawnow;
            
            % Write log file format
            if app.LogButton.Value == 1
                FileName = app.LocationEditField.Value;
                %Data = [TaggingTime, x0(1:end-1)];
                %dlmwrite(FileName, Data, '-append');
                fid = fopen(FileName, 'a') ;
                fprintf(fid, '%d,%6.3f,%6.5f,%6.5f,%s\n', TaggingTime,x0(1:end-1),app.Tag) ;
                fclose(fid) ;
                if app.Tag ~= ""
                    app.TagAtLabel.Text = sprintf('Tagging %s at : %d s',app.Tag, TaggingTime);
                end
                app.Tag = "";
                
            end
            
            
            app.ContinueousButton.Enable = 'on';
        end

        % Button pushed function: TagButton
        function TagButtonPushed(app, event)
            app.Tag = app.TagNoteEditField.Value;
        end

        % Button pushed function: SetInitialFreq
        function SetInitialFreqButtonPushed(app, event)
            app.InitialFreq.Text = num2str(app.InitResonanceF);
            app.InitialIndex.Text = num2str(app.InitIndex);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Resonance frequency')
            xlabel(app.UIAxes, 'time (s)')
            ylabel(app.UIAxes, 'frequency (Hz)')
            app.SweepcontrolPanel = uipanel(app.UIFigure);
            app.SweepcontrolPanel.Title = 'Sweep control';
            app.ContinueousButton = uibutton(app.SweepcontrolPanel, 'state');
            app.ContinueousButton.ValueChangedFcn = createCallbackFcn(app, @ContinueousButtonValueChanged, true);
            app.ContinueousButton.Text = 'Continueous';
            app.SingleSweepButton = uibutton(app.SweepcontrolPanel, 'push');
            app.SingleSweepButton.ButtonPushedFcn = createCallbackFcn(app, @SingleSweepButtonPushed, true);
            app.SingleSweepButton.Text = 'Single Sweep';
            app.LoopLabel = uilabel(app.UIFigure);
            app.LoopLabel.Text = 'Loop: ';
            app.timesLabel = uilabel(app.UIFigure);
            app.timesLabel.Text = '';
            app.LogName = uilabel(app.UIFigure);
            app.LogName.Text = '';
            app.IntervalTime = uilabel(app.UIFigure);
            app.IntervalTime.Text = '';
            app.LoggingPanel = uipanel(app.UIFigure);
            app.LoggingPanel.Title = 'Logging';
            app.BrowseButton = uibutton(app.LoggingPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.LocationEditFieldLabel = uilabel(app.LoggingPanel);
            app.LocationEditFieldLabel.Text = 'Location';
            app.LocationEditField = uieditfield(app.LoggingPanel, 'text');
            app.LogButton = uibutton(app.LoggingPanel, 'state');
            app.LogButton.ValueChangedFcn = createCallbackFcn(app, @LogButtonValueChanged, true);
            app.LogButton.Text = 'Log';
            app.TagNoteEditFieldLabel = uilabel(app.LoggingPanel);
            app.TagNoteEditFieldLabel.Text = 'Tag Note:';
            app.TagNoteEditField = uieditfield(app.LoggingPanel, 'text');
            app.TagAtLabel = uilabel(app.LoggingPanel);
            app.TagAtLabel.Text = 'TagAt';
            app.TagButton = uibutton(app.LoggingPanel, 'push');
            app.TagButton.ButtonPushedFcn = createCallbackFcn(app, @TagButtonPushed, true);
            app.TagButton.Text = 'Tag';
            app.DisplayButtonGroup = uibuttongroup(app.UIFigure);
            app.DisplayButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DisplayButtonGroupSelectionChanged, true);
            app.DisplayButtonGroup.Title = 'Display';
            app.ResistanceButton = uitogglebutton(app.DisplayButtonGroup);
            app.ResistanceButton.Text = 'Resistance';
            app.ResonanceFreqButton = uitogglebutton(app.DisplayButtonGroup);
            app.ResonanceFreqButton.Text = 'Resonance Freq';
            app.ResonanceFreqButton.Value = true;
            app.DeltaF = uitogglebutton(app.DisplayButtonGroup);
            app.DeltaF.Text = 'Delta Freq';
            app.SetInitialFreq = uibutton(app.UIFigure, 'push');
            app.SetInitialFreq.ButtonPushedFcn = createCallbackFcn(app, @SetInitialFreqButtonPushed, true);
            app.SetInitialFreq.Enable = 'off';
            app.SetInitialFreq.Visible = 'off';
            app.SetInitialFreq.Text = 'Set Initial Freq.';
            app.InitialFreq = uilabel(app.UIFigure);
            app.InitialFreq.Text = '';
            app.InitialIndex = uilabel(app.UIFigure);
            app.InitialIndex.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = BasicApp20180428

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end