classdef MultiCHApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        SweepcontrolPanel         matlab.ui.container.Panel
        ContinueousButton         matlab.ui.control.StateButton
        SingleSweepButton         matlab.ui.control.Button
        LoopLabel                 matlab.ui.control.Label
        timesLabel                matlab.ui.control.Label
        LogName                   matlab.ui.control.Label
        IntervalTime              matlab.ui.control.Label
        LoggingPanel              matlab.ui.container.Panel
        BrowseButton              matlab.ui.control.Button
        LocationEditFieldLabel    matlab.ui.control.Label
        LocationEditField         matlab.ui.control.EditField
        LogButton                 matlab.ui.control.StateButton
        LocationEditField_2Label  matlab.ui.control.Label
        LocationEditField_2       matlab.ui.control.EditField
        BrowseButton_2            matlab.ui.control.Button
        DisplayButtonGroup        matlab.ui.container.ButtonGroup
        ResistanceButton          matlab.ui.control.ToggleButton
        ResonanceFreqButton       matlab.ui.control.ToggleButton
        DeltaF                    matlab.ui.control.ToggleButton
        SetInitialFreq            matlab.ui.control.Button
        InitialFreq               matlab.ui.control.Label
        InitialIndex              matlab.ui.control.Label
    end

    
    properties (Access = private)
        N % Loop Number
        CurrentLogLocation 
        StartTime % Description
        Tag % Description
        DataArray1 % Time R ResFreq SmoothFreq 
        DataArray2
        ArrayResonanceFreq = []; % Description
        InitResonanceF %For delta F
        InitIndex %for show delta 
        
        framelen % Sgolay window
        order % Sgolay order
    end
    
    methods (Access = private)
        
        function results = RunContinueButton(app)
            tic;
            last_loop_time =0;
            while 1
                if app.ContinueousButton.Value == 1
                    
                    dir_content1 = dir('log'); % list the file from log folder
                    dir_content2 = dir('log');
                    
                    filenames1 = {dir_content1.name}; % get file name from struct
                    filenames2 = {dir_content2.name};
                    
                    current_files1 = filenames1; % current list of file name
                    current_files2 = filenames2;
                    %Pause wait for folder refresh
                    pause(0.03);
                    
                    dir_content1 = dir('log');
                    dir_content2 = dir('log');
                    
                    filenames1 = {dir_content1.name}; % get file name from struct
                    filenames2 = {dir_content2.name};
                    
                    new_files1 = setdiff(filenames1,current_files1);
                    new_files2 = setdiff(filenames2,current_files2);
                    
                    if ~isempty(new_files1)
                        % deal with the new files
                        
                        start_time =toc;
                        
                        %disp(new_files1)
                        
                        fullfilenames = fullfile('log',new_files1);
                        delimiterIn = ' ';
                        headerlinesIn = 2;
                        A = importdata(fullfilenames{1},delimiterIn,headerlinesIn);
                        
                        z0 = 50;
                        % define variable
                        freq_Array =[];
                        s11_Array = [];
                        y_Array = [];
                        
                        for n = 1:length(A.data)
                            %extract frequency from dataset
                            freq = A.data(n,1)*1E6; %convert freq data to MHz
                            freq_Array = [freq_Array; freq];
                            
                            %extract complex s parameter from dataset
                            s11 =complex(A.data(n,2),A.data(n,3));
                            s11_Array = [s11_Array; s11];
                            
                            %Directly extract to Admittance
                            try
                                y_param = s2y(s11,z0);
                            catch
                                y_param = [ 0 0; 0 0];
                            end
                            
                            y_Array = [y_Array; y_param];
                            
                        end
                        %find max F will not use
                        %[M,I] = max(real(y_Array));
                        
                        a = [400 11.400000000000 27.52015748915769 0]; %Initial condition for curve fit
                        % a = [0 0 0 0];
                        % R = 400 ohm, L= 11.4 mH, C = 27.52 fF, conductance diff = 0}
                        
                        % Curve fitting and fine minimum point
                        options = optimset('MaxFunEvals',100000,'MaxIter',10000,'TolX',1e-10,'TolFun',1e-10,'Display','off');
                        % MaxFunEvals = Maximum number of function evaluations
                        % MaxIter = Maximum number of iterations allowed
                        % TolX = Termination tolerance on x, a positive scalar. The default value is 1e-4
                        % TolFun = Termination tolerance on the function value, a positive scalar. The default is 1e-4
                        % Display = 'final' displays just the final output
                        
                        %Curve fitting result
                        [x0,fval,exitflag]=fminsearch(@(v) fitcon2(app,v,freq_Array,real(y_Array)),a,options);
                        %disp(x0)
                        
                        %Plot real and imaginary Z on same axis
                        
                        str = sprintf('R1 = %f ohm, L1 = %f mH, C1 = %f fF',x0(1),x0(2),x0(3));
                        dim = [0.2 0.5 0.3 0.3];
                        
                   
                        time_total = toc;
                        app.N = app.N+1;
                        
                        %---------------------------------------------
                        %   Tag time after end all calculation
                        %---------------------------------------------
                        TaggingTime = etime(clock,app.StartTime);
                        %---------------------------------------------
                        
                        %--------------------------------------------
                        %   Time show in textbox
                        %--------------------------------------------
                        app.LoopLabel.Text = strcat('Current Loop num: ',int2str(app.N));
                        app.timesLabel.Text = sprintf('Running time: %s s', num2str(TaggingTime));
                        app.IntervalTime.Text =  sprintf('Sampling time: %s s',num2str(time_total-last_loop_time));
                        %---------------------------------------------
                        
                        %Calculate resonance frequency
                        ResonanceFreq = (1/(2*pi*sqrt(x0(2)*x0(3))))*1e9;
                        app.InitResonanceF = ResonanceFreq;
                        
                        
                        % DataArray1 = [Time R L C ResonanceFreq] = 5 col
                        app.DataArray1 = [app.DataArray1;[TaggingTime x0(1:end-1) ResonanceFreq]];
                        [m,n] = size(app.DataArray1);
                        app.InitIndex = m;
                        
                        Initindex = str2num(app.InitialIndex.Text);

                        %update last loop time ticker
                        last_loop_time = time_total;
                        app.LogName.Text = new_files1{1};
     
                        class(app.DataArray1)
                       
                        % Write log file format
                        if app.LogButton.Value == 1
                            FileName = app.LocationEditField.Value;
                            fid = fopen(FileName, 'a') ;
                            %[Time R L C ResonanceFreq] total 5 col
                            fprintf(fid, '%.1f,%6.3f,%6.5f,%6.5f,%10.2f', app.DataArray1) ;
                            fclose(fid) ;  
                        end
                    %% Second log file    
                    elseif ~isempty(new_files2)
                        % deal with the new files
                        
                        start_time =toc;
                        
                        %disp(new_files1)
                        
                        fullfilenames = fullfile('log',new_files2);
                        delimiterIn = ' ';
                        headerlinesIn = 2;
                        A = importdata(fullfilenames{1},delimiterIn,headerlinesIn);
                        
                        z0 = 50;
                        % define variable
                        freq_Array =[];
                        s11_Array = [];
                        y_Array = [];
                        
                        for n = 1:length(A.data)
                            %extract frequency from dataset
                            freq = A.data(n,1)*1E6; %convert freq data to MHz
                            freq_Array = [freq_Array; freq];
                            
                            %extract complex s parameter from dataset
                            s11 =complex(A.data(n,2),A.data(n,3));
                            s11_Array = [s11_Array; s11];
                            
                            %Directly extract to Admittance
                            try
                                y_param = s2y(s11,z0);
                            catch
                                y_param = [ 0 0; 0 0];
                            end
                            
                            y_Array = [y_Array; y_param];
                            
                        end
                        %find max F will not use
                        %[M,I] = max(real(y_Array));
                        
                        a = [400 11.400000000000 27.52015748915769 0]; %Initial condition for curve fit
                        % a = [0 0 0 0];
                        % R = 400 ohm, L= 11.4 mH, C = 27.52 fF, conductance diff = 0}
                        
                        % Curve fitting and fine minimum point
                        options = optimset('MaxFunEvals',100000,'MaxIter',10000,'TolX',1e-10,'TolFun',1e-10,'Display','off');
                        % MaxFunEvals = Maximum number of function evaluations
                        % MaxIter = Maximum number of iterations allowed
                        % TolX = Termination tolerance on x, a positive scalar. The default value is 1e-4
                        % TolFun = Termination tolerance on the function value, a positive scalar. The default is 1e-4
                        % Display = 'final' displays just the final output
                        
                        %Curve fitting result
                        [x0,fval,exitflag]=fminsearch(@(v) fitcon2(app,v,freq_Array,real(y_Array)),a,options);
                        %disp(x0)
                        
                        %Plot real and imaginary Z on same axis
                        
                        str = sprintf('R1 = %f ohm, L1 = %f mH, C1 = %f fF',x0(1),x0(2),x0(3));
                        dim = [0.2 0.5 0.3 0.3];
                        
                   
                        time_total = toc;
                        app.N = app.N+1;
                        
                        %---------------------------------------------
                        % No time update in this second loop
                        %------------------------------------
                        
                        %Calculate resonance frequency
                        ResonanceFreq = (1/(2*pi*sqrt(x0(2)*x0(3))))*1e9;
                        app.InitResonanceF = ResonanceFreq;
                        
                        
                        % DataArray2 = [Time R L C ResonanceFreq] = 5 col
                        app.DataArray2 = [app.DataArray2;[TaggingTime x0(1:end-1) ResonanceFreq]];
                        [m,n] = size(app.DataArray2);
                        app.InitIndex = m;
                        
                        Initindex = str2num(app.InitialIndex.Text);

                       
                        app.LogName.Text = new_files2{1};
     
                        class(app.DataArray2)
                       
                        % Write log file format
                        if app.LogButton.Value == 1
                            FileName = app.LocationEditField_2.Value;
                            fid = fopen(FileName, 'a') ;
                            %[Time R L C ResonanceFreq] total 5 col
                            fprintf(fid, '%.1f,%6.3f,%6.5f,%6.5f,%10.2f', app.DataArray2) ;
                            fclose(fid) ;  
                        end
                        
                                                        
                    else
                        %fprintf('no new files\n')
                        %We will dealing with plot here let think later
                        pause(0.01)
                        if app.ResistanceButton.Value == 1
                            plot(app.UIAxes,app.DataArray1(:,1),app.DataArray1(:,2));
                            legend(app.UIAxes,'Curved Fitted measured R1','Curved Fitted measured R2');
                            legend(app.UIAxes,'Location','northwest');
                        elseif app.ResonanceFreqButton.Value == 1
                            plot(app.UIAxes,app.DataArray1(:,1),app.DataArray1(:,5),app.DataArray2(:,1),app.DataArray2(:,5),'bo');
                            legend(app.UIAxes,'Curved Fitted F1','Curved Fitted F2');
                            legend(app.UIAxes,'Location','northwest');
                        end
                    end
                else
                    %Kill VNWA process
                    %dos('taskkill /F /IM VNWA.exe');
                    
                    %Break app if value change
                    break
                end
            end
        end


    end
    
    methods (Access = public)
        
        function results = concal2(app,v,fre)
            x = v(1);
            y = v(2)/1000;
            z = v(3)*1e-15;
            results = x./(x.^2+(2*pi*fre*y-1./2/pi./fre/z).^2);
        end
        
        function results = fitcon2(app,v,Fre,Conductance)
            x = v(1);%ohm
            y = v(2)/1000;%mH
            z = v(3)*1e-15;%fF
            
            f = x./(x.^2+(2*pi*Fre*y-1./2/pi./Fre/z).^2)-Conductance;
            results=f'*f;
            
        end
               
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.N = 0;
            app.CurrentLogLocation = '';
            app.StartTime = clock;
            app.Tag = ""
            app.DataArray1 = [];
            app.DataArray2 = [];
            app.framelen = 55; 
            app.order = 3;
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [filename, pathname] = uiputfile('*.csv', 'Save a .csv log file');
            app.LocationEditField.Value  = fullfile(pathname,filename);
            
            %disp(app.CurrentLogLocation);
            %disp(app.LocationEditField.Value);
            
        end

        % Value changed function: ContinueousButton
        function ContinueousButtonValueChanged(app, event)
            if app.ContinueousButton.Value == 1
                app.SingleSweepButton.Enable = 'off'
                %Start sweep
                system('C:\VNWA\VNWA.exe -silent -csweep&')
                %Call loop function
                RunContinueButton(app);
            else
                app.SingleSweepButton.Enable = 'on'
            end
        end

        % Selection changed function: DisplayButtonGroup
        function DisplayButtonGroupSelectionChanged(app, event)
            selectedButton = app.DisplayButtonGroup.SelectedObject;
            if app.ResistanceButton.Value == 1
                app.UIAxes.YLabel.String = 'resistance (Ohm)';
                app.UIAxes.Title.String = 'Series Resistance';
                app.SetInitialFreq.Visible = 'off'
                app.SetInitialFreq.Enable = 'off'
            elseif   app.ResonanceFreqButton.Value == 1
                app.UIAxes.YLabel.String = 'frequency (Hz)';
                app.UIAxes.Title.String = 'Resonance frequency';
                app.SetInitialFreq.Visible = 'off'
                app.SetInitialFreq.Enable = 'off'
            elseif app.DeltaF.Value == 1
                app.UIAxes.YLabel.String = 'frequency (Hz)';
                app.UIAxes.Title.String = 'Delta Resonance frequency';
                app.SetInitialFreq.Visible = 'on'
                app.SetInitialFreq.Enable = 'on'
            end
        end

        % Value changed function: LogButton
        function LogButtonValueChanged(app, event)
            value = app.LogButton.Value;
            if value == 1
                app.StartTime = clock;
                app.DataArray =[];
                app.LogButton.Text = 'Logging...'; 
                %FileName = ['QCM',datestr(now, 'yyyymmdd_HHMMSS'),'.csv'];
                
                %when if file location is change write header
                %will edit later
                %if  ~strcmp(app.CurrentLogLocation,app.LocationEditField.Value)
                %    FileLocation = app.LocationEditField.Value;
                %    fid = fopen(FileLocation, 'w') ;
                %    fprintf(fid, '%s\n', 'time,R1,L1,C1,ResFreq') ;
                %    fclose(fid) ;
                %    app.CurrentLogLocation = app.LocationEditField.Value;
                %    %dlmwrite(FileName, x0(1:end-1), '-append') ;
                %end
            else
                app.LogButton.Text = 'Not Log'; 
            end
        end

        % Button pushed function: SingleSweepButton
        function SingleSweepButtonPushed(app, event)
            app.ContinueousButton.Enable = 'off';
            system('C:\VNWA\VNWA.exe C:\VNWA\test.scr -debug');
            
            delimiterIn = ' ';
            headerlinesIn = 2;
            A = importdata('s11.csv',delimiterIn,headerlinesIn);
            % initialization
            z0 = 50;
            % define variable
            freq_Array =[];
            s11_Array = [];
            y_Array = [];
            
            for n = 1:length(A.data)
                %extract frequency from dataset
                freq = A.data(n,1)*1E6; %convert freq data to MHz
                freq_Array = [freq_Array; freq];
                
                %extract complex s parameter frm dataset
                s11 =complex(A.data(n,2),A.data(n,3));
                s11_Array = [s11_Array; s11];
                
                %
                y_param = s2y(s11,z0);
                y_Array = [y_Array; y_param];
                
            end


            a = [400 11.400000000000 27.52015748915769 0]; %Initial condition for curve fit
            % a = [0 0 0 0];
            % R = 400 ohm, L= 11.4 mH, C = 27.52 fF, conductance diff = 0}
            
            % Curve fitting and fine minimum point
            options = optimset('MaxFunEvals',100000,'MaxIter',10000,'TolX',1e-10,'TolFun',1e-10,'Display','iter');
            % MaxFunEvals = Maximum number of function evaluations
            % MaxIter = Maximum number of iterations allowed
            % TolX = Termination tolerance on x, a positive scalar. The default value is 1e-4
            % TolFun = Termination tolerance on the function value, a positive scalar. The default is 1e-4
            % Display = 'final' displays just the final output
            
            
            [x0,fval,exitflag]=fminsearch(@(v) fitcon2(v,freq_Array,real(y_Array)),a,options);
            disp(x0)
            
            freqResonance = (1/(2*pi*sqrt(x0(2)*x0(3))))*1e9;
     
            TaggingTime = etime(clock,app.StartTime);
            
            plot(app.UIAxes,freq_Array,real(y_Array),freq_Array,concal2(app,x0,freq_Array))
            %plot(app.UIAxes,TaggingTime,freqResonance);
            %drawnow;
            
            % Write log file format
            if app.LogButton.Value == 1
                FileName = app.LocationEditField.Value;
                %Data = [TaggingTime, x0(1:end-1)];
                %dlmwrite(FileName, Data, '-append');
                fid = fopen(FileName, 'a') ;
                fprintf(fid, '%d,%6.3f,%6.5f,%6.5f,%s\n', TaggingTime,x0(1:end-1),app.Tag) ;
                fclose(fid) ;
                if app.Tag ~= ""
                    app.TagAtLabel.Text = sprintf('Tagging %s at : %d s',app.Tag, TaggingTime);
                end
                app.Tag = "";
                
            end
            
            
            app.ContinueousButton.Enable = 'on';
        end

        % Callback function
        function TagButtonPushed(app, event)
            app.Tag = app.TagNoteEditField.Value;
        end

        % Button pushed function: SetInitialFreq
        function SetInitialFreqButtonPushed(app, event)
            app.InitialFreq.Text = num2str(app.InitResonanceF);
            app.InitialIndex.Text = num2str(app.InitIndex);
        end

        % Button pushed function: BrowseButton_2
        function BrowseButton_2Pushed(app, event)
            [filename, pathname] = uiputfile('*.csv', 'Save a .csv log file');
            app.LocationEditField.Value  = fullfile(pathname,filename);
            
            %disp(app.CurrentLogLocation);
            %disp(app.LocationEditField.Value);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Resonance frequency')
            xlabel(app.UIAxes, 'time (s)')
            ylabel(app.UIAxes, 'frequency (Hz)')
            app.SweepcontrolPanel = uipanel(app.UIFigure);
            app.SweepcontrolPanel.Title = 'Sweep control';
            app.ContinueousButton = uibutton(app.SweepcontrolPanel, 'state');
            app.ContinueousButton.ValueChangedFcn = createCallbackFcn(app, @ContinueousButtonValueChanged, true);
            app.ContinueousButton.Text = 'Continueous';
            app.SingleSweepButton = uibutton(app.SweepcontrolPanel, 'push');
            app.SingleSweepButton.ButtonPushedFcn = createCallbackFcn(app, @SingleSweepButtonPushed, true);
            app.SingleSweepButton.Text = 'Single Sweep';
            app.LoopLabel = uilabel(app.UIFigure);
            app.LoopLabel.Text = 'Loop: ';
            app.timesLabel = uilabel(app.UIFigure);
            app.timesLabel.Text = '';
            app.LogName = uilabel(app.UIFigure);
            app.LogName.Text = '';
            app.IntervalTime = uilabel(app.UIFigure);
            app.IntervalTime.Text = '';
            app.LoggingPanel = uipanel(app.UIFigure);
            app.LoggingPanel.Title = 'Logging';
            app.BrowseButton = uibutton(app.LoggingPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.LocationEditFieldLabel = uilabel(app.LoggingPanel);
            app.LocationEditFieldLabel.Text = 'Location';
            app.LocationEditField = uieditfield(app.LoggingPanel, 'text');
            app.LogButton = uibutton(app.LoggingPanel, 'state');
            app.LogButton.ValueChangedFcn = createCallbackFcn(app, @LogButtonValueChanged, true);
            app.LogButton.Text = 'Log';
            app.LocationEditField_2Label = uilabel(app.LoggingPanel);
            app.LocationEditField_2Label.Text = 'Location';
            app.LocationEditField_2 = uieditfield(app.LoggingPanel, 'text');
            app.BrowseButton_2 = uibutton(app.LoggingPanel, 'push');
            app.BrowseButton_2.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_2Pushed, true);
            app.BrowseButton_2.Text = 'Browse';
            app.DisplayButtonGroup = uibuttongroup(app.UIFigure);
            app.DisplayButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DisplayButtonGroupSelectionChanged, true);
            app.DisplayButtonGroup.Title = 'Display';
            app.ResistanceButton = uitogglebutton(app.DisplayButtonGroup);
            app.ResistanceButton.Text = 'Resistance';
            app.ResonanceFreqButton = uitogglebutton(app.DisplayButtonGroup);
            app.ResonanceFreqButton.Text = 'Resonance Freq';
            app.ResonanceFreqButton.Value = true;
            app.DeltaF = uitogglebutton(app.DisplayButtonGroup);
            app.DeltaF.Text = 'Delta Freq';
            app.SetInitialFreq = uibutton(app.UIFigure, 'push');
            app.SetInitialFreq.ButtonPushedFcn = createCallbackFcn(app, @SetInitialFreqButtonPushed, true);
            app.SetInitialFreq.Enable = 'off';
            app.SetInitialFreq.Visible = 'off';
            app.SetInitialFreq.Text = 'Set Initial Freq.';
            app.InitialFreq = uilabel(app.UIFigure);
            app.InitialFreq.Text = '';
            app.InitialIndex = uilabel(app.UIFigure);
            app.InitialIndex.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = MultiCHApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end